classdef ALEXNET < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EntrenamientodelModeloLabel  matlab.ui.control.Label
        SeleccionarDatasetButton     matlab.ui.control.Button
        ResultLabel                  matlab.ui.control.Label
        ELPAJAROPERTENECEALAESPECIELabel  matlab.ui.control.Label
        COMPROBARButton              matlab.ui.control.Button
        SeleccionarImagenButton      matlab.ui.control.Button
        INICIARButton                matlab.ui.control.Button
        PruebadelModeloLabel         matlab.ui.control.Label
        ALEXNETReconocimientodeEspeciesdeAvesLabel  matlab.ui.control.Label
        ImageAxes                    matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DatasetPath % Propiedad para guardar la ruta del dataset
        TrainedModel      % ¡NUEVO! Para guardar la red entrenada
        SelectedImagePath % ¡NUEVO! Para guardar la ruta de la imagen a comprobar
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: INICIARButton
        function INICIARButtonPushed(app, event)


    % --- 0. Variables para guardar el historial de los gráficos ---
    trainLossHistory = [];
    trainAccHistory = [];
    valLossHistory = [];
    valAccHistory = [];
    iterationHistory = [];
    valIterationHistory = [];
    
    % Variables para el diálogo
    d = []; 
    maxEpochs = 8; 

    try
        % --- 1. Validar y obtener la ruta del Dataset ---
        if isempty(app.DatasetPath)
            uialert(app.UIFigure, '¡Error! Primero debes seleccionar un dataset.', 'Dataset no encontrado');
            return;
        end
        datasetPath = app.DatasetPath;
        
        % --- 2. Cargar y Preparar Datos (rápido) ---
        d = uiprogressdlg(app.UIFigure, 'Title', 'Preparando...', 'Message', 'Cargando imágenes...', 'Indeterminate', 'on');
        
        imds = imageDatastore(datasetPath, 'IncludeSubfolders', true, 'LabelSource', 'foldernames');
        [imdsTrain, imdsValidation] = splitEachLabel(imds, 0.8, 'randomized');
        numClasses = numel(categories(imdsTrain.Labels));
        
        % --- 3. Cargar AlexNet y Preparar Transfer Learning ---
        d.Message = 'Cargando AlexNet...';
        net = alexnet; 
        
        % ****** ¡CORRECCIÓN 1! ******
        % Volvemos a añadir esta línea. Es necesaria.
        inputSize = net.Layers(1).InputSize; 
        
        layersTransfer = net.Layers(1:end-3);
        newLayers = [
            layersTransfer
            fullyConnectedLayer(numClasses, 'WeightLearnRateFactor', 20, 'BiasLearnRateFactor', 20)
            softmaxLayer
            classificationLayer];
        
        % --- 4. Pre-procesamiento de Imágenes (CORREGIDO) ---
        d.Message = 'Procesando imágenes (Augmented Datastore)...';

        % Define "aumentaciones" SÓLO para entrenamiento
        pixelRange = [-30 30];
        imageAugmenter = imageDataAugmenter( ...
            'RandXReflection', true, ...
            'RandXTranslation', pixelRange, ...
            'RandYTranslation', pixelRange);
        
        % Crea un aumentador "vacío" para la validación.
        augmenterValidation = imageDataAugmenter(); 

        % ****** ¡CORRECCIÓN 2! ******
        % Volvemos a añadir 'inputSize(1:2)' como primer argumento.
        % No te preocupes, no redimensionará tus imágenes si ya miden 227x227.
        augimdsTrain = augmentedImageDatastore(inputSize(1:2), imdsTrain, 'DataAugmentation', imageAugmenter);
        augimdsValidation = augmentedImageDatastore(inputSize(1:2), imdsValidation, 'DataAugmentation', augmenterValidation);
        
        % --- 5. Limpiar Gráficos Anteriores ---
        cla(app.UIAxes);   
        title(app.UIAxes, 'Precisión (en tiempo real)');   
        xlabel(app.UIAxes, 'Iteración');   
        ylabel(app.UIAxes, 'Precisión'); 
        grid(app.UIAxes, 'on');
        
        cla(app.UIAxes_2); 
        title(app.UIAxes_2, 'Pérdida/Error (en tiempo real)'); 
        xlabel(app.UIAxes_2, 'Iteración'); 
        ylabel(app.UIAxes_2, 'Pérdida'); 
        grid(app.UIAxes_2, 'on');
        
        if isfield(app, 'ImageAxes')
            cla(app.ImageAxes);
            title(app.ImageAxes, '');
        end
        if isfield(app, 'ResultLabel')
            app.ResultLabel.Text = 'Resultado: ...';
        end
        
        % --- 6. Opciones de Entrenamiento ---
        miniBatchSize = 10;
        numTrainFiles = numel(augimdsTrain.Files);
        validationFrequency = max(1, floor(numTrainFiles / miniBatchSize));
        
        options = trainingOptions('sgdm', ...
            'MiniBatchSize', miniBatchSize, ...
            'MaxEpochs', maxEpochs, ... 
            'InitialLearnRate', 1e-4, ...
            'Shuffle', 'every-epoch', ...
            'ValidationData', augimdsValidation, ...
            'ValidationFrequency', validationFrequency, ...
            'Verbose', false, ...
            'ExecutionEnvironment', 'auto', ...
            'Plots', 'none', ...
            'OutputFcn', @(info) plotTrainingProgress(info));
        
        % --- 7. Entrenar la Red ---
        d.Title = 'Entrenando Red';
        d.Indeterminate = 'off'; 
        d.Value = 0; 
        
        [trainedNet, trainInfo] = trainNetwork(augimdsTrain, newLayers, options);
        
        % --- 8. Evaluación Final (para Matriz de Confusión) ---
        d.Message = 'Entrenamiento completo. Generando Matriz de Confusión...';
        d.Value = 1; 
        d.Indeterminate = 'on';
        
        [YPred, scores] = classify(trainedNet, augimdsValidation);
        YValidation = imdsValidation.Labels;
        
        figure; 
        confusionchart(YValidation, YPred, 'Title', 'Matriz de Confusión (Final)');
        
        % --- 9. Guardar Modelo y Finalizar ---
        app.TrainedModel = trainedNet; 
        
        close(d); 
        uialert(app.UIFigure, '¡Proceso Finalizado! El modelo está listo para "Comprobar".', 'Éxito');
        
    catch ME
        % --- Manejo de Errores ---
        if ~isempty(d) && isvalid(d)
            close(d); 
        end
        uialert(app.UIFigure, ['Error: ' ME.message], 'Error en el Proceso');
        rethrow(ME); 
    end

    % --- FUNCIÓN ANIDADA (HELPER) PARA PLOTEO EN TIEMPO REAL ---
    function stop = plotTrainingProgress(info)
        
        stop = false; 
        
        if info.State == "iteration"
            
            % --- 1. Actualizar Popup ---
            if mod(info.Iteration, 5) == 0 || info.Iteration == 1 
                d.Value = info.Epoch / maxEpochs;
                d.Message = sprintf('Época %d de %d, Iter: %d, Loss: %.4f', ...
                    info.Epoch, maxEpochs, info.Iteration, info.TrainingLoss);
            end
            
            % --- 2. Graficar Pérdida (Error) ---
            iterationHistory = [iterationHistory, info.Iteration];
            trainLossHistory = [trainLossHistory, info.TrainingLoss];
            
            plot(app.UIAxes_2, iterationHistory, trainLossHistory, 'b-', 'LineWidth', 1);
            legend(app.UIAxes_2, 'Entrenamiento', 'Validación', 'Location', 'northeast');
            hold(app.UIAxes_2, 'on');

            % --- 3. Graficar Precisión ---
            trainAccHistory = [trainAccHistory, info.TrainingAccuracy];
            
            plot(app.UIAxes, iterationHistory, trainAccHistory, 'b-', 'LineWidth', 1);
            legend(app.UIAxes, 'Entrenamiento', 'Validación', 'Location', 'southeast');
            hold(app.UIAxes, 'on');
            
            % --- 4. Graficar Datos de Validación (si están disponibles) ---
            if ~isnan(info.ValidationLoss)
                valIterationHistory = [valIterationHistory, info.Iteration];
                valLossHistory = [valLossHistory, info.ValidationLoss];
                valAccHistory = [valAccHistory, info.ValidationAccuracy];
                
                plot(app.UIAxes_2, valIterationHistory, valLossHistory, 'r-*', 'LineWidth', 1.5);
                plot(app.UIAxes, valIterationHistory, valAccHistory, 'r-*', 'LineWidth', 1.5);
            end
            
            hold(app.UIAxes, 'off');
            hold(app.UIAxes_2, 'off');
            
            drawnow('limitrate'); 
        end
    end % <-- Fin de la función anidada plotTrainingProgress

        end

        % Button pushed function: SeleccionarDatasetButton
        function SeleccionarDatasetButtonPushed(app, event)
            % --- Callback para el botón "Seleccionar Dataset" ---

            % Abre una ventana para que el usuario elija la carpeta
            start_path = pwd; % Empezar en la carpeta actual
            folder_name = uigetdir(start_path, 'Selecciona la carpeta del dataset (Especies de Aves)');
            
            % Verifica si el usuario seleccionó una carpeta (y no le dio a "Cancelar")
            if folder_name ~= 0
                % Si seleccionó, guarda la ruta en nuestra propiedad
                app.DatasetPath = folder_name;
                
                % (Opcional) Informa al usuario en la etiqueta de estado
                if isfield(app, 'StatusLabel')
                    app.StatusLabel.Text = ['Dataset listo: ' app.DatasetPath];
                else
                    disp(['Dataset seleccionado: ' app.DatasetPath]);
                end
            else
                % El usuario presionó "Cancelar"
                if isfield(app, 'StatusLabel')
                    app.StatusLabel.Text = 'Carga de dataset cancelada.';
                end
            end
        end

        % Button pushed function: SeleccionarImagenButton
        function SeleccionarImagenButtonPushed(app, event)
            % --- Callback para el botón "Seleccionar Imagen" ---
                % Abre una ventana para que el usuario elija una imagen
                [fileName, filePath] = uigetfile({'*.jpg;*.jpeg;*.png', 'Archivos de Imagen (*.jpg, *.jpeg, *.png)'}, ...
                    'Selecciona una imagen para clasificar');
                
                % Verifica si el usuario seleccionó un archivo
                if fileName ~= 0
                    % Construye la ruta completa
                    fullPath = fullfile(filePath, fileName);
                    
                    % 1. Guarda la ruta en nuestra propiedad
                    app.SelectedImagePath = fullPath;
                    
                    % 2. Lee y muestra la imagen en el nuevo eje
                    try
                        img = imread(fullPath);
                        imshow(img, 'Parent', app.ImageAxes); % Muestra en el eje 'ImageAxes'
                        title(app.ImageAxes, 'Imagen a Comprobar');
                        app.ResultLabel.Text = 'Resultado: Imagen cargada. Presiona "Comprobar".';
                    catch ME
                        uialert(app.UIFigure, ['Error al leer la imagen: ' ME.message], 'Error de Archivo');
                        app.SelectedImagePath = []; % Limpia la ruta si hay error
                    end
                else
                    % El usuario presionó "Cancelar"
                    app.ResultLabel.Text = 'Resultado: ...';
                end
        end

        % Button pushed function: COMPROBARButton
        function COMPROBARButtonPushed(app, event)

            % --- Verificación 1: ¿El modelo está entrenado? ---
            if isempty(app.TrainedModel)
                uialert(app.UIFigure, 'Error: Primero debes entrenar el modelo con el botón "INICIAR".', 'Modelo no encontrado');
                return;
            end
            
            % --- Verificación 2: ¿Se seleccionó una imagen? ---
            if isempty(app.SelectedImagePath)
                uialert(app.UIFigure, 'Error: Primero debes "Seleccionar Imagen".', 'Imagen no encontrada');
                return;
            end
            
            % Si todo está bien, procedemos
            try
                app.ResultLabel.Text = 'Resultado: Clasificando...';
                drawnow; % Forzar actualización del texto
                
                % 1. Obtener el tamaño de entrada que AlexNet espera (227x227)
                inputSize = app.TrainedModel.Layers(1).InputSize(1:2);
                
                % 2. Leer la imagen seleccionada
                img = imread(app.SelectedImagePath);
                
                % 3. Pre-procesar la imagen (redimensionar)
                % Esto es necesario por si el usuario sube una imagen
                % de un tamaño diferente a 227x227.
                imgResized = imresize(img, inputSize);
                
                % 4. ¡Clasificar la imagen!
                % 'YPred' es la mejor predicción (ej. "Flamengo")
                % 'probs' es un vector con TODAS las probabilidades (ej. [0.70 0.25 0.05])
                [YPred, probs] = classify(app.TrainedModel, imgResized);

                % 5. Obtener y ordenar las N predicciones principales
                
                % Obtener los nombres de todas las clases (ej. ["Flamengo", "Lechuza"])
                classNames = app.TrainedModel.Layers(end).ClassNames;
                
                % Convertir probabilidades a porcentaje (ej. [70 25 5])
                probsPercent = probs * 100;
                
                % Ordenar las probabilidades de mayor a menor
                [sortedProbs, sortedIndices] = sort(probsPercent, 'descend');
                
                % Obtener las 3 mejores (o menos, si hay pocas clases)
                numToShow = min(3, numel(classNames)); % Muestra 3, o 2 si solo hay 2 clases
                
                topProbs = sortedProbs(1:numToShow);
                topNames = classNames(sortedIndices(1:numToShow));
        
                % 6. Construir el texto de resultado
                
                % Siempre mostrar la predicción principal
                mainPredictionStr = sprintf('Predicción: %s\nConfianza: %.2f%%', string(topNames(1)), topProbs(1));
        
                % Si la confianza NO es 100% y hay más de una clase
                if topProbs(1) < 100 && numToShow > 1
                    
                    % Crear el texto para las "otras posibilidades"
                    otherPredictions = "\n\nOtras Posibilidades:";
                    for i = 2:numToShow
                        % Añadir cada predicción extra
                        otherPredictions = otherPredictions + sprintf('\n  - %s: %.2f%%', string(topNames(i)), topProbs(i));
                    end
                    
                    % Combinar el string principal + las otras
                    finalResultStr = mainPredictionStr + otherPredictions;
                    
                else
                    % Si es 100%, solo mostrar la principal
                    finalResultStr = mainPredictionStr;
                end
                
                % --- FIN DE LA NUEVA LÓGICA ---
        
                % 7. Mostrar el resultado en la etiqueta
                app.ResultLabel.Text = finalResultStr;
                
                % Opcional: Poner la predicción principal como título de la imagen
                title(app.ImageAxes, sprintf('Predicción: %s', string(YPred)));
                
            catch ME
                uialert(app.UIFigure, ['Error al clasificar: ' ME.message], 'Error de Clasificación');
                app.ResultLabel.Text = 'Resultado: Error.';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'nebula')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Title')
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            zlabel(app.ImageAxes, 'Z')
            app.ALEXNETReconocimientodeEspeciesdeAvesLabel = uilabel(app.UIFigure);
            app.ALEXNETReconocimientodeEspeciesdeAvesLabel.Text = 'ALEXNET (Reconocimiento de Especies de Aves)';
            app.PruebadelModeloLabel = uilabel(app.UIFigure);
            app.PruebadelModeloLabel.Text = 'Prueba del Modelo';
            app.INICIARButton = uibutton(app.UIFigure, 'push');
            app.INICIARButton.ButtonPushedFcn = createCallbackFcn(app, @INICIARButtonPushed, true);
            app.INICIARButton.Text = 'INICIAR';
            app.SeleccionarImagenButton = uibutton(app.UIFigure, 'push');
            app.SeleccionarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarImagenButtonPushed, true);
            app.SeleccionarImagenButton.Text = 'SELECCIONAR IMAGEN';
            app.COMPROBARButton = uibutton(app.UIFigure, 'push');
            app.COMPROBARButton.ButtonPushedFcn = createCallbackFcn(app, @COMPROBARButtonPushed, true);
            app.COMPROBARButton.Text = 'COMPROBAR';
            app.ELPAJAROPERTENECEALAESPECIELabel = uilabel(app.UIFigure);
            app.ELPAJAROPERTENECEALAESPECIELabel.Text = 'EL PAJARO PERTENECE A LA ESPECIE :';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Label2';
            app.SeleccionarDatasetButton = uibutton(app.UIFigure, 'push');
            app.SeleccionarDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarDatasetButtonPushed, true);
            app.SeleccionarDatasetButton.Text = 'SUBIR DATASET';
            app.EntrenamientodelModeloLabel = uilabel(app.UIFigure);
            app.EntrenamientodelModeloLabel.Text = 'Entrenamiento del Modelo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ALEXNET

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end